classdef Task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        INEditFieldLabel           matlab.ui.control.Label
        INEditField                matlab.ui.control.EditField
        OutEditFieldLabel          matlab.ui.control.Label
        OutEditField               matlab.ui.control.EditField
        SelectOutFontListBoxLabel  matlab.ui.control.Label
        SelectOutFontListBox       matlab.ui.control.ListBox
        TasksEditFieldLabel        matlab.ui.control.Label
        TasksEditField             matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectOutFontListBox.Items = listfonts;
        end

        % Callback function: INEditField, OutEditField
        function INEditFieldValueChanged(app, event)
            value = app.INEditField.Value;
            app.OutEditField.Value = value;
            app.OutEditField.FontName = app.SelectOutFontListBox.Value;
        end

        % Value changed function: SelectOutFontListBox
        function SelectOutFontListBoxValueChanged(app, event)
            app.OutEditField.FontName = app.SelectOutFontListBox.Value;   
            app.OutEditField.Value = app.OutEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.INEditFieldLabel = uilabel(app.UIFigure);
            app.INEditFieldLabel.Text = 'IN';
            app.INEditField = uieditfield(app.UIFigure, 'text');
            app.INEditField.ValueChangedFcn = createCallbackFcn(app, @INEditFieldValueChanged, true);
            app.OutEditFieldLabel = uilabel(app.UIFigure);
            app.OutEditFieldLabel.Text = 'Out';
            app.OutEditField = uieditfield(app.UIFigure, 'text');
            app.OutEditField.ValueChangingFcn = createCallbackFcn(app, @INEditFieldValueChanged, true);
            app.OutEditField.Editable = 'off';
            app.SelectOutFontListBoxLabel = uilabel(app.UIFigure);
            app.SelectOutFontListBoxLabel.Text = 'Select Out Font';
            app.SelectOutFontListBox = uilistbox(app.UIFigure);
            app.SelectOutFontListBox.ValueChangedFcn = createCallbackFcn(app, @SelectOutFontListBoxValueChanged, true);
            app.TasksEditFieldLabel = uilabel(app.UIFigure);
            app.TasksEditFieldLabel.Text = 'Tasks';
            app.TasksEditField = uieditfield(app.UIFigure, 'text');
            app.TasksEditField.Editable = 'off';
            app.TasksEditField.Value = 'GUI for Problem 1 and Problem 2 of Ex 6';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Task

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end