classdef Task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        Task2EditFieldLabel     matlab.ui.control.Label
        Task2EditField          matlab.ui.control.EditField
        CurrentXEditFieldLabel  matlab.ui.control.Label
        CurrentXEditField       matlab.ui.control.NumericEditField
        CurrentYEditFieldLabel  matlab.ui.control.Label
        CurrentYEditField       matlab.ui.control.NumericEditField
        StartButton             matlab.ui.control.StateButton
        MarkerSettingsPanel     matlab.ui.container.Panel
        MarkerDropDownLabel     matlab.ui.control.Label
        MarkerDropDown          matlab.ui.control.DropDown
        SizeSpinnerLabel        matlab.ui.control.Label
        SizeSpinner             matlab.ui.control.Spinner
        SetcolorButton          matlab.ui.control.Button
        DeltaKnobLabel          matlab.ui.control.Label
        DeltaKnob               matlab.ui.control.Knob
        StepSizeEditFieldLabel  matlab.ui.control.Label
        StepSizeEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        plt = '';   % Handle to plot
        t = 0;
        pd = makedist('Uniform','Lower',0,'Upper',2*pi);
        dt = 0.0005;
        Step = 0.1;
        delta = 1;
        ha = '';
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.plt = plot(app.UIAxes,app.CurrentXEditField.Value, app.CurrentYEditField.Value, 'o', 'MarkerFaceColor', 'r', 'MarkerSize', 5,'MarkerEdgeColor', 'k');            
            app.DeltaKnob.MajorTicks = 1:10:100;
            app.DeltaKnob.MinorTicks = 1:5:100;            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.ha = app.UIAxes;
            app.t = app.t + app.dt;
            while app.StartButton.Value == 0
                app.plt.XData = app.plt.XData + app.Step*(cos(pdf(app.pd,app.t)));
                app.plt.YData = app.plt.YData + app.Step*(sin(pdf(app.pd,app.t)));
                app.CurrentXEditField.Value = app.plt.XData;
                app.CurrentYEditField.Value = app.plt.YData;
                drawnow;
                app.t = app.t + app.dt + app.Step;
                
                if app.plt.XData < app.ha.XLim(1)
                    app.ha.XLim(1) = app.ha.XLim(1) - app.delta;
                elseif app.plt.XData > app.ha.XLim(2)
                    app.ha.XLim(2) = app.ha.XLim(2) + app.delta;
                end
                
                if app.plt.YData < app.ha.YLim(1)
                    app.ha.YLim(1) = app.ha.YLim(1) - app.delta;
                elseif app.plt.YData > app.ha.YLim(2)
                    app.ha.YLim(2) = app.ha.YLim(2) + app.delta;
                end
            end
            
        end

        % Value changed function: MarkerDropDown
        function MarkerDropDownValueChanged(app, event)
            app.plt.Marker = app.MarkerDropDown.Value;
            
        end

        % Button pushed function: SetcolorButton
        function SetcolorButtonPushed(app, event)
            app.plt.MarkerFaceColor = uisetcolor;
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            app.Step = app.StepSizeEditField.Value;
            
        end

        % Value changed function: SizeSpinner
        function SizeSpinnerValueChanged(app, event)
            app.plt.MarkerSize = app.SizeSpinner.Value;
            
        end

        % Value changed function: DeltaKnob
        function DeltaKnobValueChanged(app, event)
            app.delta = app.DeltaKnob.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Random Walk')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Task2EditFieldLabel = uilabel(app.UIFigure);
            app.Task2EditFieldLabel.Text = 'Task 2';
            app.Task2EditField = uieditfield(app.UIFigure, 'text');
            app.Task2EditField.Editable = 'off';
            app.Task2EditField.Value = 'GUI for Problem 3 and Problem 4 of Ex 6';
            app.CurrentXEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentXEditFieldLabel.Text = 'Current X ';
            app.CurrentXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentXEditField.Value = 2;
            app.CurrentYEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentYEditFieldLabel.Text = 'Current Y';
            app.CurrentYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentYEditField.Value = 2;
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StartButton.Value = true;
            app.MarkerSettingsPanel = uipanel(app.UIFigure);
            app.MarkerSettingsPanel.Title = 'Marker Settings';
            app.MarkerDropDownLabel = uilabel(app.MarkerSettingsPanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.MarkerSettingsPanel);
            app.MarkerDropDown.Items = {'Circle', 'Plus sign', 'Asterisk', 'Diamond'};
            app.MarkerDropDown.ItemsData = {'o', '+', '*', 'd'};
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerDropDownValueChanged, true);
            app.MarkerDropDown.Value = 'o';
            app.SizeSpinnerLabel = uilabel(app.MarkerSettingsPanel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.MarkerSettingsPanel);
            app.SizeSpinner.Limits = [2 50];
            app.SizeSpinner.ValueDisplayFormat = '%.0f';
            app.SizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SizeSpinnerValueChanged, true);
            app.SizeSpinner.Value = 5;
            app.SetcolorButton = uibutton(app.MarkerSettingsPanel, 'push');
            app.SetcolorButton.ButtonPushedFcn = createCallbackFcn(app, @SetcolorButtonPushed, true);
            app.SetcolorButton.Text = 'Set color';
            app.DeltaKnobLabel = uilabel(app.UIFigure);
            app.DeltaKnobLabel.Text = 'Delta';
            app.DeltaKnob = uiknob(app.UIFigure, 'continuous');
            app.DeltaKnob.ValueChangedFcn = createCallbackFcn(app, @DeltaKnobValueChanged, true);
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.LowerLimitInclusive = 'off';
            app.StepSizeEditField.Limits = [0 10];
            app.StepSizeEditField.ValueDisplayFormat = '%.3f';
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Value = 0.001;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Task2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end